classdef windows < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LabelMsg                      matlab.ui.control.Label
        GereomeuFiltroButton          matlab.ui.control.Button
        ParmetrosdeEntradaSeletivosPanel  matlab.ui.container.Panel
        DropDown_3                    matlab.ui.control.DropDown
        TipodeJanelaLabel             matlab.ui.control.Label
        DropDown_2                    matlab.ui.control.DropDown
        TipodeFiltroLabel             matlab.ui.control.Label
        ParmetrosdeEntradaNumtricosPanel  matlab.ui.container.Panel
        dBLabel_2                     matlab.ui.control.Label
        RippleEditField               matlab.ui.control.NumericEditField
        RippleEditFieldLabel          matlab.ui.control.Label
        HzLabel_4                     matlab.ui.control.Label
        HzLabel_3                     matlab.ui.control.Label
        dBLabel                       matlab.ui.control.Label
        HzLabel_2                     matlab.ui.control.Label
        HzLabel                       matlab.ui.control.Label
        TxdeAmostragemEditField       matlab.ui.control.NumericEditField
        TxdeAmostragemEditFieldLabel  matlab.ui.control.Label
        LarguradaBandadeTransioEditField_3  matlab.ui.control.NumericEditField
        LarguradaBandadeTransioEditField_3Label  matlab.ui.control.Label
        AtenuaoEditField_3            matlab.ui.control.NumericEditField
        AtenuaoEditField_3Label       matlab.ui.control.Label
        Frequncia2EditField_3         matlab.ui.control.NumericEditField
        Frequncia2EditField_3Label    matlab.ui.control.Label
        Frequncia1EditField_3         matlab.ui.control.NumericEditField
        Frequncia1EditField_3Label    matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        filtersPath = addpath('filters')
        windowsPath = addpath('windows')
        window = 'Retangular' % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SelectWindow(app, event)
            app.window = app.DropDown.Value;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if app.DropDown_2.Value == ("Passa-Banda")
                set(app.Frequncia2EditField_3,'Enable','on')
                set(app.Frequncia2EditField_3Label,'Enable','on')
            elseif app.DropDown_2.Value == ("Rejeita-Banda")
                set(app.Frequncia2EditField_3,'Enable','on')
                set(app.Frequncia2EditField_3Label,'Enable','on')
            elseif app.DropDown_2.Value == ("Passa-Baixa")
                set(app.Frequncia2EditField_3,'Enable','off')
                set(app.Frequncia2EditField_3Label,'Enable','off')
                set(app.Frequncia2EditField_3,'Value',0)
            elseif app.DropDown_2.Value == ("Passa-Alta")
                set(app.Frequncia2EditField_3,'Enable','off')
                set(app.Frequncia2EditField_3Label,'Enable','off')
                set(app.Frequncia2EditField_3,'Value',0)
            end

        end

        % Callback function
        function AtenuaoEditField_2ValueChanging(app, event)
            changingValue = event.Value;
            if (changingValue) < 0
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            elseif (changingValue) > 74
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
                DropDown_3ValueChanged(app,event)
            end
        end

        % Value changed function: AtenuaoEditField_3
        function AtenuaoEditField_2ValueChanged(app, event)
            value = app.AtenuaoEditField_3.Value;
            if app.AtenuaoEditField_3.Value < 0
                set(app.GereomeufiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            elseif (app.AtenuaoEditField_3.Value) > 74
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
                DropDown_3ValueChanged(app,event)
            end
            
        end

        % Button pushed function: GereomeuFiltroButton
        function GereomeuFiltroButtonPushed(app, event)
            if (app.DropDown_2.Value == "Passa-Baixa" || app.DropDown_2.Value == "Passa-Alta") && app.Frequncia1EditField_3.Value == 0
                    app.LabelMsg.Text = 'Preencha todos os campos';
            elseif (app.DropDown_2.Value == "Passa-Banda" || app.DropDown_2.Value == "Rejeita-Faixa") && (app.Frequncia1EditField_3.Value == 0 || app.Frequncia2EditField_3.Value == 0)
                 
                    app.LabelMsg.Text = 'Preencha todos os campos';
                
            elseif app.AtenuaoEditField_3.Value == 0 || app.TxdeAmostragemEditField.Value == 0 || app.LarguradaBandadeTransioEditField_3.Value == 0 || app.RippleEditField.Value == 0
                app.LabelMsg.Text = 'Preencha todos os campos';
            else
                feedback = main_init(app.Frequncia1EditField_3.Value, app.Frequncia2EditField_3.Value, app.TxdeAmostragemEditField.Value, app.LarguradaBandadeTransioEditField_3.Value, app.AtenuaoEditField_3.Value, app.DropDown_2.Value, app.DropDown_3.Value, app.RippleEditField.Value);
                app.LabelMsg.Text = feedback;
                disp('tudo certo')
            end
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if app.DropDown_3.Value == "Retangular" && (app.AtenuaoEditField_3.Value) > 21
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                
            elseif (app.DropDown_3.Value == "Bartlett") && (app.AtenuaoEditField_3.Value) > 25 
                
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                
            elseif (app.DropDown_3.Value == "Hanning") && (app.AtenuaoEditField_3.Value) > 44 
                
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                
            elseif (app.DropDown_3.Value == "Hamming") && (app.AtenuaoEditField_3.Value) > 53
                
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                
            elseif (app.AtenuaoEditField_3.Value) > 74
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
                set(app.Image2, 'Visible', 'off')
            end
        end

        % Value changed function: TxdeAmostragemEditField
        function TaxadeAmostragemEditField_2ValueChanging(app, event)
            changingValue = event.Value;
            if (app.Frequncia1EditField_3.Value) >= (app.Frequncia2EditField_3.Value)
                mv = (app.Frequncia1EditField_3.Value);
            else
                mv = (app.Frequncia2EditField_3.Value);
            end
            if (changingValue) > 2*mv
                set(app.GereomeuFiltroButton, 'Enable', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'off')
            end

        end

        % Callback function
        function Frequncia1EditField_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = event.Value;
            if (changingValue) >= (app.Frequncia2EditField_3.Value)
                mv = (changingValue)
            else
                mv = (app.Frequncia2EditField_3.Value)
            end
            if (app.TxdeAmostragemEditField.Value) <= 2*mv
                set(app.GereomeuFiltroButton, 'Enable', 'off')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
            end
        end

        % Callback function
        function Frequncia2EditField_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = event.Value;
            if (app.Frequncia1EditField_3.Value) >= (changingValue)
                mv = (app.Frequncia1EditField_3.Value)
            else
                mv = (changingValue)
            end
            if (app.TxdeAmostragemEditField.Value)  <= 2*mv
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                %disp("a")
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'wave.png');
            app.ParmetrosdeEntradaNumtricosPanel = uipanel(app.UIFigure);
            app.ParmetrosdeEntradaNumtricosPanel.Title = 'Parâmetros de Entrada Numétricos';
            app.Image2 = uiimage(app.ParmetrosdeEntradaNumtricosPanel);
            app.Image2.Visible = 'off';
            app.Image2.Tooltip = {'Tente reduzir o valor da atenuação.'};
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'ex.png');
            app.Frequncia1EditField_3Label = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.Frequncia1EditField_3Label.Text = 'Frequência 1';
            app.Frequncia1EditField_3 = uieditfield(app.ParmetrosdeEntradaNumtricosPanel, 'numeric');
            app.Frequncia2EditField_3Label = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.Frequncia2EditField_3Label.Enable = 'off';
            app.Frequncia2EditField_3Label.Text = 'Frequência 2';
            app.Frequncia2EditField_3 = uieditfield(app.ParmetrosdeEntradaNumtricosPanel, 'numeric');
            app.Frequncia2EditField_3.Enable = 'off';
            app.AtenuaoEditField_3Label = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.AtenuaoEditField_3Label.Text = 'Atenuação';
            app.AtenuaoEditField_3 = uieditfield(app.ParmetrosdeEntradaNumtricosPanel, 'numeric');
            app.AtenuaoEditField_3.ValueChangedFcn = createCallbackFcn(app, @AtenuaoEditField_2ValueChanged, true);
            app.AtenuaoEditField_3.Tooltip = {'Faixa de Rejeição'};
            app.LarguradaBandadeTransioEditField_3Label = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.LarguradaBandadeTransioEditField_3Label.Text = {'Largura da'; 'Banda de Transição'};
            app.LarguradaBandadeTransioEditField_3 = uieditfield(app.ParmetrosdeEntradaNumtricosPanel, 'numeric');
            app.TxdeAmostragemEditFieldLabel = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.TxdeAmostragemEditFieldLabel.Text = 'Tx. de Amostragem';
            app.TxdeAmostragemEditField = uieditfield(app.ParmetrosdeEntradaNumtricosPanel, 'numeric');
            app.TxdeAmostragemEditField.ValueChangedFcn = createCallbackFcn(app, @TaxadeAmostragemEditField_2ValueChanging, true);
            app.HzLabel = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.HzLabel_2.Text = 'Hz';
            app.dBLabel = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.dBLabel.Text = 'dB';
            app.HzLabel_3 = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.HzLabel_3.Text = 'Hz';
            app.HzLabel_4 = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.HzLabel_4.Text = 'Hz';
            app.RippleEditFieldLabel = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.RippleEditFieldLabel.Text = 'Ripple';
            app.RippleEditField = uieditfield(app.ParmetrosdeEntradaNumtricosPanel, 'numeric');
            app.RippleEditField.Tooltip = {'Faixa de Passagem'};
            app.dBLabel_2 = uilabel(app.ParmetrosdeEntradaNumtricosPanel);
            app.dBLabel_2.Text = 'dB';
            app.ParmetrosdeEntradaSeletivosPanel = uipanel(app.UIFigure);
            app.ParmetrosdeEntradaSeletivosPanel.Title = 'Parâmetros de Entrada Seletivos';
            app.TipodeFiltroLabel = uilabel(app.ParmetrosdeEntradaSeletivosPanel);
            app.TipodeFiltroLabel.Text = 'Tipo de Filtro';
            app.DropDown_2 = uidropdown(app.ParmetrosdeEntradaSeletivosPanel);
            app.DropDown_2.Items = {'Passa-Baixa', 'Passa-Alta', 'Passa-Banda', 'Rejeita-Faixa'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Passa-Baixa';
            app.TipodeJanelaLabel = uilabel(app.ParmetrosdeEntradaSeletivosPanel);
            app.TipodeJanelaLabel.Text = 'Tipo de Janela';
            app.DropDown_3 = uidropdown(app.ParmetrosdeEntradaSeletivosPanel);
            app.DropDown_3.Items = {'Auto (Padrão)', 'Hanning', 'Blackman', 'Retangular', 'Hamming', 'Bartlett'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = 'Auto (Padrão)';
            app.GereomeuFiltroButton = uibutton(app.UIFigure, 'push');
            app.GereomeuFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @GereomeuFiltroButtonPushed, true);
            app.GereomeuFiltroButton.Text = 'Gere o meu Filtro!';
            app.LabelMsg = uilabel(app.UIFigure);
            app.LabelMsg.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = windows

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end