classdef windows2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GereomeuFiltroButton         matlab.ui.control.Button
        ParmetrosdeEntradaSeletivosPanel  matlab.ui.container.Panel
        DropDown_3                   matlab.ui.control.DropDown
        TipodeJanelaLabel            matlab.ui.control.Label
        DropDown_2                   matlab.ui.control.DropDown
        TipodeFiltroLabel            matlab.ui.control.Label
        ParmetrosdeEntradaArbitrriosPanel  matlab.ui.container.Panel
        Frequncia1EditField_2        matlab.ui.control.EditField
        Frequncia1EditField_2Label   matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        TaxadeAmostragemEditField_2  matlab.ui.control.EditField
        TaxadeAmostragemEditField_2Label  matlab.ui.control.Label
        LarguradaBandadeTransioEditField_2  matlab.ui.control.EditField
        LarguradaBandadeTransioEditField_2Label  matlab.ui.control.Label
        AtenuaoEditField_2           matlab.ui.control.EditField
        AtenuaoEditField_2Label      matlab.ui.control.Label
        Frequncia2EditField_2        matlab.ui.control.EditField
        Frequncia2EditField_2Label   matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        filtersPath = addpath('filters')
        windowsPath = addpath('windows')
        window = 'Retangular' % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SubmitWindow(app, event)
            plot_window(app.window, app.EditField.Value)
        end

        % Callback function
        function SelectWindow(app, event)
            app.window = app.DropDown.Value;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if app.DropDown_2.Value == ("Passa-Banda")
                set(app.Frequncia2EditField_2,'Enable','on')
                set(app.Frequncia2EditField_2Label,'Enable','on')
            elseif app.DropDown_2.Value == ("Rejeita-Banda")
                set(app.Frequncia2EditField_2,'Enable','on')
                set(app.Frequncia2EditField_2Label,'Enable','on')
            elseif app.DropDown_2.Value == ("Passa-Baixa")
                set(app.Frequncia2EditField_2,'Enable','off')
                set(app.Frequncia2EditField_2Label,'Enable','off')
                set(app.Frequncia2EditField_2,'Value','0')
            elseif app.DropDown_2.Value == ("Passa-Alta")
                set(app.Frequncia2EditField_2,'Enable','off')
                set(app.Frequncia2EditField_2Label,'Enable','off')
                set(app.Frequncia2EditField_2,'Value','0')
            end

        end

        % Value changing function: AtenuaoEditField_2
        function AtenuaoEditField_2ValueChanging(app, event)
            changingValue = event.Value;
            if str2num(changingValue) < 0
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            elseif str2num(changingValue) > 74
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
                DropDown_3ValueChanged(app,event)
            end
        end

        % Value changed function: AtenuaoEditField_2
        function AtenuaoEditField_2ValueChanged(app, event)
            value = app.AtenuaoEditField_2.Value;
            if str2num(app.AtenuaoEditField_2.Value) < 0
                set(app.GereomeufiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            elseif str2num(app.AtenuaoEditField_2.Value) > 74
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
                DropDown_3ValueChanged(app,event)
            end
            
        end

        % Button pushed function: GereomeuFiltroButton
        function GereomeuFiltroButtonPushed(app, event)
            main_init(app.Frequncia1EditField_2.Value, app.Frequncia2EditField_2.Value, app.TaxadeAmostragemEditField_2.Value, app.LarguradaBandadeTransioEditField_2.Value, app.AtenuaoEditField_2.Value, app.DropDown_2.Value, app.DropDown_3.Value)
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            if app.DropDown_3.Value == "Retangular"
                if str2num(app.AtenuaoEditField_2.Value) > 21
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                end
            elseif (app.DropDown_3.Value == "Bartlett")
                if str2num(app.AtenuaoEditField_2.Value) > 25 
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                end
            elseif (app.DropDown_3.Value == "Hanning")
                if str2num(app.AtenuaoEditField_2.Value) > 44 
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                end
            elseif (app.DropDown_3.Value == "Hamming")
                if str2num(app.AtenuaoEditField_2.Value) > 53
                    set(app.GereomeuFiltroButton, 'Enable', 'off')
                    set(app.Image2, 'Visible', 'on')
                end
            elseif str2num(app.AtenuaoEditField_2.Value) > 74
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                set(app.Image2, 'Visible', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
                set(app.Image2, 'Visible', 'off')
            end
        end

        % Value changing function: TaxadeAmostragemEditField_2
        function TaxadeAmostragemEditField_2ValueChanging(app, event)
            changingValue = event.Value;
            if str2num(app.Frequncia1EditField_2.Value) >= str2num(app.Frequncia2EditField_2.Value)
                mv = str2num(app.Frequncia1EditField_2.Value)
            else
                mv = str2num(app.Frequncia2EditField_2.Value)
            end
            if str2num(changingValue) > 2*mv
                set(app.GereomeuFiltroButton, 'Enable', 'on')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'off')
            end

        end

        % Value changing function: Frequncia1EditField_2
        function Frequncia1EditField_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = event.Value;
            if str2num(changingValue) >= str2num(app.Frequncia2EditField_2.Value)
                mv = str2num(changingValue)
            else
                mv = str2num(app.Frequncia2EditField_2.Value)
            end
            if str2num(app.TaxadeAmostragemEditField_2.Value) <= 2*mv
                set(app.GereomeuFiltroButton, 'Enable', 'off')
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
            end
        end

        % Value changing function: Frequncia2EditField_2
        function Frequncia2EditField_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue = event.Value;
            if str2num(app.Frequncia1EditField_2.Value) >= str2num(changingValue)
                mv = str2num(app.Frequncia1EditField_2.Value)
            else
                mv = str2num(changingValue)
            end
            if str2num(app.TaxadeAmostragemEditField_2.Value)  <= 2*mv
                set(app.GereomeuFiltroButton, 'Enable', 'off')
                %disp("a")
            else
                set(app.GereomeuFiltroButton, 'Enable', 'on')
            end
        end

        % Value changed function: TaxadeAmostragemEditField_2
        function TaxadeAmostragemEditField_2ValueChanged(app, event)
            value = app.TaxadeAmostragemEditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'wave.png');
            app.ParmetrosdeEntradaArbitrriosPanel = uipanel(app.UIFigure);
            app.ParmetrosdeEntradaArbitrriosPanel.Title = 'Parâmetros de Entrada Arbitrários';
            app.Frequncia2EditField_2Label = uilabel(app.ParmetrosdeEntradaArbitrriosPanel);
            app.Frequncia2EditField_2Label.Enable = 'off';
            app.Frequncia2EditField_2Label.Text = 'Frequência 2';
            app.Frequncia2EditField_2 = uieditfield(app.ParmetrosdeEntradaArbitrriosPanel, 'text');
            app.Frequncia2EditField_2.ValueChangingFcn = createCallbackFcn(app, @Frequncia2EditField_2ValueChanging, true);
            app.Frequncia2EditField_2.Enable = 'off';
            app.Frequncia2EditField_2.Value = '1';
            app.AtenuaoEditField_2Label = uilabel(app.ParmetrosdeEntradaArbitrriosPanel);
            app.AtenuaoEditField_2Label.Text = 'Atenuação';
            app.AtenuaoEditField_2 = uieditfield(app.ParmetrosdeEntradaArbitrriosPanel, 'text');
            app.AtenuaoEditField_2.ValueChangedFcn = createCallbackFcn(app, @AtenuaoEditField_2ValueChanged, true);
            app.AtenuaoEditField_2.ValueChangingFcn = createCallbackFcn(app, @AtenuaoEditField_2ValueChanging, true);
            app.AtenuaoEditField_2.Value = '1';
            app.LarguradaBandadeTransioEditField_2Label = uilabel(app.ParmetrosdeEntradaArbitrriosPanel);
            app.LarguradaBandadeTransioEditField_2Label.Text = {'Largura da'; 'Banda de Transição'};
            app.LarguradaBandadeTransioEditField_2 = uieditfield(app.ParmetrosdeEntradaArbitrriosPanel, 'text');
            app.LarguradaBandadeTransioEditField_2.Value = '1';
            app.TaxadeAmostragemEditField_2Label = uilabel(app.ParmetrosdeEntradaArbitrriosPanel);
            app.TaxadeAmostragemEditField_2Label.Text = 'Taxa de Amostragem';
            app.TaxadeAmostragemEditField_2 = uieditfield(app.ParmetrosdeEntradaArbitrriosPanel, 'text');
            app.TaxadeAmostragemEditField_2.ValueChangedFcn = createCallbackFcn(app, @TaxadeAmostragemEditField_2ValueChanged, true);
            app.TaxadeAmostragemEditField_2.ValueChangingFcn = createCallbackFcn(app, @TaxadeAmostragemEditField_2ValueChanging, true);
            app.TaxadeAmostragemEditField_2.Value = '1';
            app.Image2 = uiimage(app.ParmetrosdeEntradaArbitrriosPanel);
            app.Image2.Visible = 'off';
            app.Image2.Tooltip = {'Tente reduzir o valor da atenuação.'};
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'images', 'ex.png');
            app.Frequncia1EditField_2Label = uilabel(app.ParmetrosdeEntradaArbitrriosPanel);
            app.Frequncia1EditField_2Label.Text = 'Frequência 1';
            app.Frequncia1EditField_2 = uieditfield(app.ParmetrosdeEntradaArbitrriosPanel, 'text');
            app.Frequncia1EditField_2.ValueChangingFcn = createCallbackFcn(app, @Frequncia1EditField_2ValueChanging, true);
            app.Frequncia1EditField_2.Value = '1';
            app.ParmetrosdeEntradaSeletivosPanel = uipanel(app.UIFigure);
            app.ParmetrosdeEntradaSeletivosPanel.Title = 'Parâmetros de Entrada Seletivos';
            app.TipodeFiltroLabel = uilabel(app.ParmetrosdeEntradaSeletivosPanel);
            app.TipodeFiltroLabel.Text = 'Tipo de Filtro';
            app.DropDown_2 = uidropdown(app.ParmetrosdeEntradaSeletivosPanel);
            app.DropDown_2.Items = {'Passa-Baixa', 'Passa-Alta', 'Passa-Banda', 'Rejeita-Faixa'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Passa-Baixa';
            app.TipodeJanelaLabel = uilabel(app.ParmetrosdeEntradaSeletivosPanel);
            app.TipodeJanelaLabel.Text = 'Tipo de Janela';
            app.DropDown_3 = uidropdown(app.ParmetrosdeEntradaSeletivosPanel);
            app.DropDown_3.Items = {'Auto (Padrão)', 'Hanning', 'Blackman', 'Retangular', 'Hamming', 'Bartlett'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = 'Auto (Padrão)';
            app.GereomeuFiltroButton = uibutton(app.UIFigure, 'push');
            app.GereomeuFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @GereomeuFiltroButtonPushed, true);
            app.GereomeuFiltroButton.Text = 'Gere o meu Filtro!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = windows2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end